classdef Heat_SIM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Examples50xx3y50Label         matlab.ui.control.Label
        Examplesxy50x50sinxexpxlogxLabel  matlab.ui.control.Label
        ThefollowingoperationsmustbeusedwhendealingwithvariablesxandyLabel  matlab.ui.control.Label
        AdditionandsubstractioncanbewritteninconventionalformLabel  matlab.ui.control.Label
        Label_4                       matlab.ui.control.Label
        Label_3                       matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        NotesonwritingMATLABfunctionsLabel  matlab.ui.control.Label
        RightTempstr                  matlab.ui.control.EditField
        TemperaturexytLabel_4         matlab.ui.control.Label
        LeftTempstr                   matlab.ui.control.EditField
        TemperaturexytLabel_3         matlab.ui.control.Label
        BottomTempstr                 matlab.ui.control.EditField
        TemperaturexytLabel_2         matlab.ui.control.Label
        TopTempstr                    matlab.ui.control.EditField
        TemperaturexytLabel           matlab.ui.control.Label
        timestep                      matlab.ui.control.NumericEditField
        TimestepEditFieldLabel        matlab.ui.control.Label
        alphavalue                    matlab.ui.control.NumericEditField
        alphaLabel                    matlab.ui.control.Label
        maxtime                       matlab.ui.control.NumericEditField
        MaxtimeLabel                  matlab.ui.control.Label
        TimeSlider                    matlab.ui.control.Slider
        TimeSliderLabel               matlab.ui.control.Label
        yNodes                        matlab.ui.control.NumericEditField
        yNodesEditFieldLabel          matlab.ui.control.Label
        xNodes                        matlab.ui.control.NumericEditField
        xNodesEditFieldLabel          matlab.ui.control.Label
        initialtemp                   matlab.ui.control.EditField
        InitialtemperaturefunctionLabel  matlab.ui.control.Label
        ylength                       matlab.ui.control.NumericEditField
        EnterylengthofrectangleEditFieldLabel  matlab.ui.control.Label
        xlength                       matlab.ui.control.NumericEditField
        EnterxlengthofrectangleEditFieldLabel  matlab.ui.control.Label
        NoteBoundarytemperaturefunctionsareonlyineffectwhenDirichletconditionisselectedLabel  matlab.ui.control.Label
        EnterDimensionsLabel          matlab.ui.control.Label
        RightBoundaryLabel            matlab.ui.control.Label
        LeftBoundaryLabel             matlab.ui.control.Label
        BottomBoundaryLabel           matlab.ui.control.Label
        TopBoundaryLabel              matlab.ui.control.Label
        fracpartialupartialtkleftfracpartial2upartialx2fracpartial2upartialy2rightLabel  matlab.ui.control.Label
        EvaluateButton                matlab.ui.control.Button
        RightBC                       matlab.ui.control.DropDown
        LeftBC                        matlab.ui.control.DropDown
        BottomBC                      matlab.ui.control.DropDown
        TopBC                         matlab.ui.control.DropDown
        EnterBoundaryConditionsLabel  matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        ResultMatrix % Description
        HeatMapPlot
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: xlength
        function xlengthValueChanged(app, event)
            xlength = app.xlength.Value;
            
        end

        % Value changed function: ylength
        function ylengthValueChanged(app, event)
            ylength = app.ylength.Value;
            
        end

        % Value changed function: xNodes
        function xNodesValueChanged(app, event)
            xNodes = app.xNodes.Value;
            
        end

        % Value changed function: yNodes
        function yNodesValueChanged(app, event)
            yNodes = app.yNodes.Value;
            
        end

        % Value changed function: initialtemp
        function initialtempValueChanged(app, event)

        end

        % Value changed function: maxtime
        function maxtimeValueChanged(app, event)
            value = app.maxtime.Value;
            
        end

        % Button pushed function: EvaluateButton
        function EvaluateButtonPushed(app, event)
            % initialize dynamic boundary function handles
            wrapBC = @(str) str2func(['@(x, y, t) ' str]);
            wrapInitialTemp = @(str) str2func(['@(x,y) ' str]);

            bcFuncs = {wrapBC(app.LeftTempstr.Value), 
                wrapBC(app.RightTempstr.Value),
                wrapBC(app.BottomTempstr.Value), 
                wrapBC(app.TopTempstr.Value)};
            
            % collecting inputs
            Lx = app.xlength.Value; 
            Ly = app.ylength.Value;
            Nx = app.xNodes.Value; 
            Ny = app.yNodes.Value;
            dx = Lx / (Nx - 1); 
            dy = Ly / (Ny - 1);
            alpha = app.alphavalue.Value;
            tMax = app.maxtime.Value;
            init_func = wrapInitialTemp(app.initialtemp.Value);
            dt = app.timestep.Value; 
            num_steps = ceil(tMax/dt);
            N = Nx * Ny;
            
            % pre-calculate boundary indices for speed
            left_nodes   = 1:Ny;
            right_nodes  = (Nx-1)*Ny + (1:Ny);
            bottom_nodes = 1:Ny:N; 
            top_nodes    = Ny:Ny:N;
            
            % initialize matrices
            A = sparse(N, N); 
            B = sparse(N, N);
            rx = alpha * dt / (2 * dx^2); 
            ry = alpha * dt / (2 * dy^2);
            
            % constructing matrices
            for i = 1:Nx
                for j = 1:Ny
                    k = (i-1)*Ny + j;
                    if i == 1 || i == Nx || j == 1 || j == Ny
                        % identify if Dirichlet
                        isDir = (i==1 && app.LeftBC.Value=="Dirichlet") || ...
                                (i==Nx && app.RightBC.Value=="Dirichlet") || ...
                                (j==1 && app.BottomBC.Value=="Dirichlet") || ...
                                (j==Ny && app.TopBC.Value=="Dirichlet");
                        if isDir
                            A(k,k) = 1; 
                            B(k,k) = 0;
                        else % Neumann
                            if i==1, A(k,k)=1; A(k,k+Ny)=-1; 
                            elseif i==Nx, A(k,k)=1; A(k,k-Ny)=-1;
                            elseif j==1, A(k,k)=1; A(k,k+1)=-1; 
                            elseif j==Ny, A(k,k)=1; A(k,k-1)=-1; 
                            end
                            B(k,:) = 0;
                        end
                    else
                        % interior CN stencil
                        A(k,k) = 1 + 2*rx + 2*ry; 
                        A(k,k-1) = -ry; 
                        A(k,k+1) = -ry; 
                        A(k,k-Ny) = -rx; 
                        A(k,k+Ny) = -rx;

                        B(k,k) = 1 - 2*rx - 2*ry; 
                        B(k,k-1) = ry; 
                        B(k,k+1) = ry; 
                        B(k,k-Ny) = rx; 
                        B(k,k+Ny) = rx;
                    end
                end
            end
            
            % pre-factorize A for performance
            [L_fac, U_fac, P_fac, Q_fac, R_fac] = lu(A);
                   
            % initial Condition
            [X, Y] = meshgrid(linspace(0,Lx,Nx), linspace(0,Ly,Ny));
            u_vec = feval(init_func, X, Y); u_vec = u_vec(:);
            
            app.ResultMatrix = zeros(Ny, Nx, num_steps);

            X_left = X(left_nodes);
            X_right = X(right_nodes);
            X_top = X(top_nodes);
            X_bottom = X(bottom_nodes);
            Y_left = Y(left_nodes);
            Y_right = Y(right_nodes);
            Y_top = Y(top_nodes);
            Y_bottom = Y(bottom_nodes);

            for t = 1:num_steps
                currT = t * dt;
                b_const = zeros(N, 1);
                
                % apply dirichlet conditions
                % bcFuncs are handles defined as @(x,y,t) ...
                if app.LeftBC.Value == "Dirichlet"
                    b_const(left_nodes) = bcFuncs{1}(X_left, Y_left, currT);
                end
                
                if app.RightBC.Value == "Dirichlet"
                    b_const(right_nodes) = bcFuncs{2}(X_right, Y_right, currT);
                end
                
                if app.BottomBC.Value == "Dirichlet"
                    b_const(bottom_nodes) = bcFuncs{3}(X_bottom, Y_bottom, currT);
                end
                
                if app.TopBC.Value == "Dirichlet"
                    b_const(top_nodes) = bcFuncs{4}(X_top, Y_top, currT);
                end
               
                % right hand side
                rhs = B * u_vec + b_const;
                
                % solving for new time values
                u_vec = Q_fac * (U_fac \ (L_fac \ (P_fac * (R_fac \ rhs))));
                
                % Store the snapshot
                app.ResultMatrix(:, :, t) = reshape(u_vec, Ny, Nx);
            end
            
            % visual
            app.HeatMapPlot = imagesc(app.UIAxes, [0 Lx], [0 Ly], app.ResultMatrix(:,:,1));
            axis(app.UIAxes, 'tight'); axis(app.UIAxes, 'xy');
            colorbar(app.UIAxes); caxis(app.UIAxes, [min(app.ResultMatrix(:)), max(app.ResultMatrix(:))]);
            app.TimeSlider.Limits = [1, num_steps]; app.TimeSlider.Value = 1;
        end

        % Value changed function: alphavalue
        function alphavalueValueChanged(app, event)
            value = app.alphavalue.Value;
            
        end

        % Value changed function: TopBC
        function TopBCValueChanged(app, event)
            value = app.TopBC.Value;
            
        end

        % Value changed function: BottomBC
        function BottomBCValueChanged(app, event)
            value = app.BottomBC.Value;
            
        end

        % Value changed function: LeftBC
        function LeftBCValueChanged(app, event)
            value = app.LeftBC.Value;
            
        end

        % Value changed function: RightBC
        function RightBCValueChanged(app, event)
            value = app.RightBC.Value;
            
        end

        % Value changing function: TimeSlider
        function TimeSliderValueChanging(app, event)
            idx = round(event.Value);
            
            if isempty(app.ResultMatrix)
                return;
            end
            
            % Update ONLY the pixel data
            set(app.HeatMapPlot, 'CData', app.ResultMatrix(:, :, idx));
        end

        % Value changed function: timestep
        function timestepValueChanged(app, event)
            value = app.timestep.Value;
            
        end

        % Value changed function: TopTempstr
        function TopTempstrValueChanged(app, event)
            value = app.TopTempstr.Value;
            
        end

        % Value changed function: BottomTempstr
        function BottomTempstrValueChanged(app, event)
            value = app.BottomTempstr.Value;
            
        end

        % Value changed function: LeftTempstr
        function LeftTempstrValueChanged(app, event)
            value = app.LeftTempstr.Value;
            
        end

        % Value changed function: RightTempstr
        function RightTempstrValueChanged(app, event)
            value = app.RightTempstr.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Heatmap')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.EnterBoundaryConditionsLabel = uilabel(app.UIFigure);
            app.EnterBoundaryConditionsLabel.Text = 'Enter Boundary Conditions';
            app.TopBC = uidropdown(app.UIFigure);
            app.TopBC.Items = {'Dirichlet', 'Neumann'};
            app.TopBC.ValueChangedFcn = createCallbackFcn(app, @TopBCValueChanged, true);
            app.TopBC.Value = 'Dirichlet';
            app.BottomBC = uidropdown(app.UIFigure);
            app.BottomBC.Items = {'Dirichlet', 'Neumann'};
            app.BottomBC.ValueChangedFcn = createCallbackFcn(app, @BottomBCValueChanged, true);
            app.BottomBC.Value = 'Dirichlet';
            app.LeftBC = uidropdown(app.UIFigure);
            app.LeftBC.Items = {'Dirichlet', 'Neumann'};
            app.LeftBC.ValueChangedFcn = createCallbackFcn(app, @LeftBCValueChanged, true);
            app.LeftBC.Value = 'Dirichlet';
            app.RightBC = uidropdown(app.UIFigure);
            app.RightBC.Items = {'Dirichlet', 'Neumann'};
            app.RightBC.ValueChangedFcn = createCallbackFcn(app, @RightBCValueChanged, true);
            app.RightBC.Value = 'Dirichlet';
            app.EvaluateButton = uibutton(app.UIFigure, 'push');
            app.EvaluateButton.ButtonPushedFcn = createCallbackFcn(app, @EvaluateButtonPushed, true);
            app.EvaluateButton.Text = 'Evaluate';
            app.fracpartialupartialtkleftfracpartial2upartialx2fracpartial2upartialy2rightLabel = uilabel(app.UIFigure);
            app.fracpartialupartialtkleftfracpartial2upartialx2fracpartial2upartialy2rightLabel.Interpreter = 'latex';
            app.fracpartialupartialtkleftfracpartial2upartialx2fracpartial2upartialy2rightLabel.Text = '$\frac{\partial u}{\partial t} = k \left( \frac{\partial ^2u}{\partial x^2} +\frac{\partial ^2 u}{\partial y^2} \right)$';
            app.TopBoundaryLabel = uilabel(app.UIFigure);
            app.TopBoundaryLabel.Text = 'Top Boundary';
            app.BottomBoundaryLabel = uilabel(app.UIFigure);
            app.BottomBoundaryLabel.Text = 'Bottom Boundary';
            app.LeftBoundaryLabel = uilabel(app.UIFigure);
            app.LeftBoundaryLabel.Text = 'Left Boundary';
            app.RightBoundaryLabel = uilabel(app.UIFigure);
            app.RightBoundaryLabel.Text = 'Right Boundary';
            app.EnterDimensionsLabel = uilabel(app.UIFigure);
            app.EnterDimensionsLabel.Text = 'Enter Dimensions';
            app.NoteBoundarytemperaturefunctionsareonlyineffectwhenDirichletconditionisselectedLabel = uilabel(app.UIFigure);
            app.NoteBoundarytemperaturefunctionsareonlyineffectwhenDirichletconditionisselectedLabel.Text = {'Note: Boundary temperature functions are only in effect when'; 'Dirichlet condition is selected.'};
            app.EnterxlengthofrectangleEditFieldLabel = uilabel(app.UIFigure);
            app.EnterxlengthofrectangleEditFieldLabel.Text = 'Enter x-length of rectangle';
            app.xlength = uieditfield(app.UIFigure, 'numeric');
            app.xlength.ValueChangedFcn = createCallbackFcn(app, @xlengthValueChanged, true);
            app.xlength.Value = 1;
            app.EnterylengthofrectangleEditFieldLabel = uilabel(app.UIFigure);
            app.EnterylengthofrectangleEditFieldLabel.Text = 'Enter y-length of rectangle';
            app.ylength = uieditfield(app.UIFigure, 'numeric');
            app.ylength.ValueChangedFcn = createCallbackFcn(app, @ylengthValueChanged, true);
            app.ylength.Value = 1;
            app.InitialtemperaturefunctionLabel = uilabel(app.UIFigure);
            app.InitialtemperaturefunctionLabel.Text = 'Initial temperature function (x,y):';
            app.initialtemp = uieditfield(app.UIFigure, 'text');
            app.initialtemp.ValueChangedFcn = createCallbackFcn(app, @initialtempValueChanged, true);
            app.initialtemp.Value = '50*ones(size(x))';
            app.xNodesEditFieldLabel = uilabel(app.UIFigure);
            app.xNodesEditFieldLabel.Text = 'x Nodes';
            app.xNodes = uieditfield(app.UIFigure, 'numeric');
            app.xNodes.ValueChangedFcn = createCallbackFcn(app, @xNodesValueChanged, true);
            app.xNodes.Value = 40;
            app.yNodesEditFieldLabel = uilabel(app.UIFigure);
            app.yNodesEditFieldLabel.Text = 'y Nodes';
            app.yNodes = uieditfield(app.UIFigure, 'numeric');
            app.yNodes.ValueChangedFcn = createCallbackFcn(app, @yNodesValueChanged, true);
            app.yNodes.Value = 40;
            app.TimeSliderLabel = uilabel(app.UIFigure);
            app.TimeSliderLabel.Text = 'Time';
            app.TimeSlider = uislider(app.UIFigure);
            app.TimeSlider.ValueChangingFcn = createCallbackFcn(app, @TimeSliderValueChanging, true);
            app.MaxtimeLabel = uilabel(app.UIFigure);
            app.MaxtimeLabel.Text = 'Max time:';
            app.maxtime = uieditfield(app.UIFigure, 'numeric');
            app.maxtime.ValueChangedFcn = createCallbackFcn(app, @maxtimeValueChanged, true);
            app.maxtime.Value = 5;
            app.alphaLabel = uilabel(app.UIFigure);
            app.alphaLabel.Interpreter = 'latex';
            app.alphaLabel.Text = '$k=$';
            app.alphavalue = uieditfield(app.UIFigure, 'numeric');
            app.alphavalue.ValueChangedFcn = createCallbackFcn(app, @alphavalueValueChanged, true);
            app.alphavalue.Value = 0.01;
            app.TimestepEditFieldLabel = uilabel(app.UIFigure);
            app.TimestepEditFieldLabel.Text = 'Time step:';
            app.timestep = uieditfield(app.UIFigure, 'numeric');
            app.timestep.ValueChangedFcn = createCallbackFcn(app, @timestepValueChanged, true);
            app.timestep.Value = 0.1;
            app.TemperaturexytLabel = uilabel(app.UIFigure);
            app.TemperaturexytLabel.Text = 'Temperature(x,y,t) = ';
            app.TopTempstr = uieditfield(app.UIFigure, 'text');
            app.TopTempstr.ValueChangedFcn = createCallbackFcn(app, @TopTempstrValueChanged, true);
            app.TopTempstr.Value = '0';
            app.TemperaturexytLabel_2 = uilabel(app.UIFigure);
            app.TemperaturexytLabel_2.Text = 'Temperature(x,y,t) = ';
            app.BottomTempstr = uieditfield(app.UIFigure, 'text');
            app.BottomTempstr.ValueChangedFcn = createCallbackFcn(app, @BottomTempstrValueChanged, true);
            app.BottomTempstr.Value = '0';
            app.TemperaturexytLabel_3 = uilabel(app.UIFigure);
            app.TemperaturexytLabel_3.Text = 'Temperature(x,y,t) = ';
            app.LeftTempstr = uieditfield(app.UIFigure, 'text');
            app.LeftTempstr.ValueChangedFcn = createCallbackFcn(app, @LeftTempstrValueChanged, true);
            app.LeftTempstr.Value = '0';
            app.TemperaturexytLabel_4 = uilabel(app.UIFigure);
            app.TemperaturexytLabel_4.Text = 'Temperature(x,y,t) = ';
            app.RightTempstr = uieditfield(app.UIFigure, 'text');
            app.RightTempstr.ValueChangedFcn = createCallbackFcn(app, @RightTempstrValueChanged, true);
            app.RightTempstr.Value = '0';
            app.NotesonwritingMATLABfunctionsLabel = uilabel(app.UIFigure);
            app.NotesonwritingMATLABfunctionsLabel.Text = 'Notes on writing MATLAB functions:';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '.*';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = './';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '.^';
            app.AdditionandsubstractioncanbewritteninconventionalformLabel = uilabel(app.UIFigure);
            app.AdditionandsubstractioncanbewritteninconventionalformLabel.Interpreter = 'latex';
            app.AdditionandsubstractioncanbewritteninconventionalformLabel.Text = 'Addition and substraction can be written in conventional form.';
            app.ThefollowingoperationsmustbeusedwhendealingwithvariablesxandyLabel = uilabel(app.UIFigure);
            app.ThefollowingoperationsmustbeusedwhendealingwithvariablesxandyLabel.Interpreter = 'latex';
            app.ThefollowingoperationsmustbeusedwhendealingwithvariablesxandyLabel.Text = 'The following operations must be used when dealing with variables $x$ and $y$:';
            app.Examplesxy50x50sinxexpxlogxLabel = uilabel(app.UIFigure);
            app.Examplesxy50x50sinxexpxlogxLabel.Interpreter = 'latex';
            app.Examplesxy50x50sinxexpxlogxLabel.Text = 'Examples: $x+y$, $50 - x$, 50 *$\sin (x)$, exp$(x)$, $\log (x)$';
            app.Examples50xx3y50Label = uilabel(app.UIFigure);
            app.Examples50xx3y50Label.Interpreter = 'latex';
            app.Examples50xx3y50Label.Text = 'Examples: 50 .* $x$, $x$ .^ 3, y ./ 50';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Heat_SIM

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end